classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TabGroup         matlab.ui.container.TabGroup
        MainTab          matlab.ui.container.Tab
        pnlEnroll        matlab.ui.container.Panel
        tblEnrollQueue   matlab.ui.control.Table
        btnEnrollBack    matlab.ui.control.Button
        btnEnrollSave    matlab.ui.control.Button
        btnEnrollLoad    matlab.ui.control.Button
        axEnrollPreview  matlab.ui.control.UIAxes
        pnlHome          matlab.ui.container.Panel
        GridLayout       matlab.ui.container.GridLayout
        btnMenuMass      matlab.ui.control.Button
        btnMenuEnroll    matlab.ui.control.Button
        btnMenuIdentify  matlab.ui.control.Button
        Label_2          matlab.ui.control.Label
        pnlMass          matlab.ui.container.Panel
        gaugeProgress    matlab.ui.control.LinearGauge
        btnMassBack      matlab.ui.control.Button
        tblMassResults   matlab.ui.control.Table
        lblMassStatus    matlab.ui.control.Label
        pnlIdentify      matlab.ui.container.Panel
        Grid             matlab.ui.container.GridLayout
        lblStatus        matlab.ui.control.Label
        lampMatch        matlab.ui.control.Lamp
        btnBackToHome    matlab.ui.control.Button
        axStep6          matlab.ui.control.UIAxes
        axStep5          matlab.ui.control.UIAxes
        axStep4          matlab.ui.control.UIAxes
        axStep3          matlab.ui.control.UIAxes
        axStep2          matlab.ui.control.UIAxes
        axStep1          matlab.ui.control.UIAxes
        LabTab           matlab.ui.container.Tab
        sliderGabor      matlab.ui.control.Slider
        SliderLabel      matlab.ui.control.Label
        axLabPreview     matlab.ui.control.UIAxes
        DatabaseTab      matlab.ui.container.Tab
        Label            matlab.ui.control.Label
    end

    
    properties (Access = public)
           FingerprintDB       % מאגר הנתונים
    CurrentConfig       % הגדרות
    CurrentImage        % תמונה נוכחית
    CurrentMinutiae     % נקודות
    CurrentDescriptors  % מאפיינים
    end
    
    properties (Access = private)
        TempEnrollData % Description
    end
    % שנה את השורה הראשונה ל-public


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. הגדרת נתיבים (הפונקציה שיצרנו קודם)
    % חשוב מאוד: זה מבטיח שהאפליקציה תכיר את כל הפונקציות בתיקיית src
    setup_project();
    
    % 2. טעינת הגדרות
    app.CurrentConfig = get_config();
    
    % 3. טעינת המאגר (אם קיים)
    dbPath = app.CurrentConfig.db_filename;
    if isfile(dbPath)
        try
            data = load(dbPath, 'fingerprintDB');
            if isfield(data, 'fingerprintDB')
                app.FingerprintDB = data.fingerprintDB;
                statusMsg = ['Database loaded: ' num2str(length(app.FingerprintDB)) ' users.'];
            else
                app.FingerprintDB = [];
                statusMsg = 'Database file found but empty/invalid.';
            end
        catch
            app.FingerprintDB = [];
            statusMsg = 'Error loading database.';
        end
    else
        app.FingerprintDB = [];
        statusMsg = 'Database not found. Starting empty.';
    end
    
    % עדכון הסטטוס במסך (אם הוספת Label בשם lblStatus)
    % אם עדיין לא הוספת, אפשר למחוק את השורה הזו בינתיים
    app.lblStatus.Text = statusMsg;
    
    % 4. איפוס נורה (אם הוספת)
    app.lampMatch.Color = [0.5 0.5 0.5]; % אפור
    % אתחול תצוגה - מראה רק את הבית
app.pnlHome.Visible = 'on';       % מציג את הבית
app.pnlIdentify.Visible = 'off';  % מסתיר את המעבדה
% app.pnlEnroll.Visible = 'off';  % (כשיהיה מוכן)
        end

        % Button pushed function: btnMenuIdentify
        function btnMenuIdentifyButtonPushed(app, event)
            % 1. שלב ראשון: בחירת קובץ בודד
    dataDir = 'data/';
    if ~exist(dataDir, 'dir'), dataDir = pwd; end
    
    [file, path] = uigetfile({'*.tif;*.png;*.jpg', 'Fingerprint Images'}, 'בחר אצבע לזיהוי', dataDir);
    if isequal(file, 0), return; end % ביטול
    
    % 2. איפוס תצוגה ומעבר למסך הנכון
    % --- התיקון הקריטי כאן: מכבים את כל האחרים ומדליקים רק את Identify ---
    app.pnlHome.Visible = 'off';
    app.pnlMass.Visible = 'off';    % וודא שזה כבוי!
    app.pnlEnroll.Visible = 'off';  % וודא שזה כבוי!
    app.pnlIdentify.Visible = 'on'; % רק זה דולק!
    drawnow;
    
    % איפוס מחוונים ויזואליים
    app.lblStatus.Text = 'מעבד תמונה...';
    app.lblStatus.FontColor = [0 0 1]; % כחול
    app.lampMatch.Color = [0.9 0.9 0.9]; % אפור (כבוי)
    
    try
        % 3. טעינה ועיבוד (6 התחנות)
        fullPath = fullfile(path, file);
        app.CurrentImage = imread(fullPath);
        
        % קריאה לפונקציה הגרפית שמציירת את השלבים
        [template, ~, ~, descriptors] = process_fingerprint_GUI(app.CurrentImage, app);
        
        % 4. בדיקה אם המאגר ריק
        if isempty(app.FingerprintDB)
            app.lblStatus.Text = 'המאגר ריק (תצוגה בלבד)';
            uialert(app.UIFigure, 'אין משתמשים במאגר. לא ניתן לבצע זיהוי.', 'מידע');
            return;
        end
        
        % 5. ביצוע הזיהוי (Matching)
        app.lblStatus.Text = 'מחפש במאגר...';
        drawnow;
        
        bestScore = 0;
        bestName = 'Unknown';
        
        % הכנת הנתונים להשוואה
        inputData.minutiae = template;
        inputData.descriptors = descriptors;
        
        % לולאה על המאגר
        for i = 1:length(app.FingerprintDB)
            dbData.minutiae = app.FingerprintDB(i).template;
            dbData.descriptors = app.FingerprintDB(i).descriptors;
            
            if isfield(app.FingerprintDB(i), 'T_sq')
                dbData.T_sq = app.FingerprintDB(i).T_sq;
            end
            
            [score, ~] = find_best_match(dbData, inputData);
            
            if score > bestScore
                bestScore = score;
                bestName = app.FingerprintDB(i).name;
            end
        end
        
        % 6. הצגת התוצאה
        threshold = 15;
        if isfield(app.CurrentConfig.match, 'pass_threshold')
            threshold = app.CurrentConfig.match.pass_threshold;
        end
        
        if bestScore >= threshold
            % === זיהוי מוצלח ===
            app.lblStatus.Text = ['זוהה: ' bestName];
            app.lblStatus.FontColor = [0 0.6 0]; % ירוק
            app.lampMatch.Color = [0 1 0];       % מנורה ירוקה
        else
            % === אין זיהוי ===
            app.lblStatus.Text = 'לא נמצאה התאמה';
            app.lblStatus.FontColor = [0.8 0 0]; % אדום
            app.lampMatch.Color = [1 0 0];       % מנורה אדומה
        end
        
    catch err
        app.lblStatus.Text = 'שגיאה';
        uialert(app.UIFigure, ['שגיאה בתהליך: ' err.message], 'Error');
    end
        end

        % Button pushed function: btnBackToHome
        function btnBackToHomeButtonPushed(app, event)
            % חזרה לבית
    app.pnlIdentify.Visible = 'off';
    app.pnlHome.Visible = 'on';
        end

        % Button pushed function: btnMenuMass
        function btnMenuMassButtonPushed(app, event)
  % 1. הגדרת סוגי קבצים נתמכים
    fileTypes = {'*.tif;*.png;*.jpg;*.bmp', 'Image Files (*.tif, *.png, *.jpg)'; ...
                 '*.*', 'All Files (*.*)'};
    
    dataDir = 'data/';
    if ~exist(dataDir, 'dir'), dataDir = pwd; end
    
    % 2. בחירת קבצים מרובים (MultiSelect = on)
    [files, path] = uigetfile(fileTypes, 'בחר קבצים לזיהוי', dataDir, 'MultiSelect', 'on');
    
    % בדיקת ביטול
    if isequal(files, 0)
        return; 
    end
    
    % נרמול הקלט: אם נבחר קובץ אחד, להפוך אותו לתא (Cell) כדי שהקוד יעבוד תמיד
    if ischar(files)
        files = {files}; 
    end
    
    % בדיקה שהמאגר לא ריק
    if isempty(app.FingerprintDB)
        uialert(app.UIFigure, 'המאגר ריק. נא לבצע רישום משתמשים קודם.', 'שגיאה');
        return;
    end

    % 3. מעבר למסך התוצאות
    app.pnlHome.Visible = 'off';
    app.pnlIdentify.Visible = 'off';
    app.pnlMass.Visible = 'on'; 
    drawnow; 

    % 4. קריאה לפונקציה החיצונית
    % אנו שולחים לה את רשימת הקבצים (files) ואת הנתיב (path)
    try
        run_mass_identification(app, files, path);
    catch err
        uialert(app.UIFigure, ['שגיאה בהרצת הזיהוי: ' err.message], 'Error');
    end
        end

        % Button pushed function: btnMassBack
        function btnMassBackButtonPushed(app, event)
            % הסתרת מסך המוני
    app.pnlMass.Visible = 'off';
    
    % חזרה לבית
    app.pnlHome.Visible = 'on';
        end

        % Button pushed function: btnMenuEnroll
        function btnMenuEnrollButtonPushed(app, event)
            % מעבר למסך ההרשמה
    app.pnlHome.Visible = 'off';
    app.pnlEnroll.Visible = 'on';
    
    % --- איפוס המסך (מותאם לטבלה החדשה) ---
    app.TempEnrollData = [];       % ניקוי זיכרון
    app.tblEnrollQueue.Data = {};  % ניקוי הטבלה
    cla(app.axEnrollPreview);      % ניקוי התמונה
    
    % איפוס כותרת התמונה
    title(app.axEnrollPreview, 'תצוגה מקדימה');
        end

        % Button pushed function: btnEnrollLoad
        function btnEnrollLoadButtonPushed(app, event)
            % 1. בחירת קבצים
    [files, path] = uigetfile({'*.tif;*.png;*.jpg', 'Images'}, ...
                              'בחר קבצים להרשמה', 'data/', 'MultiSelect', 'on');
    if isequal(files, 0), return; end
    if ischar(files), files = {files}; end 
    
    totalFiles = length(files);
    
    % הגדרת כותרות הטבלה (מימין לשמאל לעברית)
    app.tblEnrollQueue.ColumnName = {'סטטוס', 'נקודות', 'שם מזוהה', 'שם קובץ'};
    
    % איפוסים
    app.TempEnrollData = []; 
    app.tblEnrollQueue.Data = {};
    
    tableDisplayData = {};
    tempList = []; 
    
    % 2. לולאת עיבוד
    for k = 1:totalFiles
        filename = files{k};
        fullPath = fullfile(path, filename);
        [~, nameNoExt, ~] = fileparts(filename);
        
        % עדכון סטטוס בכותרת התמונה
        title(app.axEnrollPreview, sprintf('מעבד %d/%d...', k, totalFiles));
        drawnow limitrate;
        
        try
            [data, img, err] = load_and_process_image(fullPath, app.CurrentConfig.enroll.min_minutiae);
            
            if ~isempty(err)
                status = '❌ נכשל';
                ptsCount = 0;
            else
                status = '✅ תקין';
                ptsCount = size(data.minutiae, 1);
                
                newItem.name = nameNoExt;
                newItem.minutiae = data.minutiae;
                newItem.descriptors = data.descriptors;
                newItem.imagePath = fullPath;
                newItem.processedImg = img;
                
                if isempty(tempList), tempList = newItem; else, tempList(end+1) = newItem; end
            end
        catch
            status = '⚠️ שגיאה';
            ptsCount = 0;
        end
        
        % עדכון הטבלה (סדר הפוך לעברית)
        tableDisplayData(end+1, :) = {status, ptsCount, nameNoExt, filename};
    end
    
    % 3. סיום
    app.tblEnrollQueue.Data = tableDisplayData;
    app.TempEnrollData = tempList; 
    
    title(app.axEnrollPreview, 'בחר שורה בטבלה להצגה');
    
    if ~isempty(tempList)
        imshow(tempList(1).processedImg, 'Parent', app.axEnrollPreview);
        hold(app.axEnrollPreview, 'on');
        plot(app.axEnrollPreview, tempList(1).minutiae(:,1), tempList(1).minutiae(:,2), 'r.');
        hold(app.axEnrollPreview, 'off');
    end
    
    % --- התיקון: הודעת הצלחה עם אייקון ירוק ---
    msg = sprintf('סריקה הושלמה: %d קבצים נבדקו.\n%d נמצאו תקינים לשמירה.', totalFiles, length(tempList));
    uialert(app.UIFigure, msg, 'הצלחה', 'Icon', 'success');
        end

        % Button pushed function: btnEnrollSave
        function btnEnrollSaveButtonPushed(app, event)
            % 1. בדיקה שיש נתונים מוכנים לשמירה
    if isempty(app.TempEnrollData)
        uialert(app.UIFigure, 'אין נתונים תקינים לשמירה. נא לטעון קבצים.', 'ריק', 'Icon', 'warning');
        return;
    end
    
    newUsersList = app.TempEnrollData;
    count = length(newUsersList);
    
    % 2. לולאת הוספה למאגר
    for i = 1:count
        newEntry = struct();
        newEntry.name = newUsersList(i).name;
        newEntry.template = newUsersList(i).minutiae;
        newEntry.descriptors = newUsersList(i).descriptors;
        newEntry.imagePath = newUsersList(i).imagePath;
        
        % חישוב T_sq
        newEntry.T_sq = sum(newEntry.template(:,1:2).^2, 2);
        
        if isempty(app.FingerprintDB)
            app.FingerprintDB = newEntry;
        else
            app.FingerprintDB(end+1) = newEntry;
        end
    end
    
    % 3. שמירה לקובץ ויציאה
    try
        fingerprintDB = app.FingerprintDB;
        save(app.CurrentConfig.db_filename, 'fingerprintDB');
        
        % --- כאן השינוי: הוספת האייקון הירוק ---
        msg = sprintf('נוספו בהצלחה %d משתמשים למאגר!', count);
        uialert(app.UIFigure, msg, 'הצלחה', 'Icon', 'success');
        
        % ניקוי נתונים
        app.TempEnrollData = [];
        app.tblEnrollQueue.Data = {};
        cla(app.axEnrollPreview);
        title(app.axEnrollPreview, 'תצוגה מקדימה');
        
        % חזרה למסך הבית
        app.pnlEnroll.Visible = 'off';
        app.pnlHome.Visible = 'on';
        
    catch err
        uialert(app.UIFigure, ['שגיאה בשמירה: ' err.message], 'Error', 'Icon', 'error');
    end
        end

        % Button pushed function: btnEnrollBack
        function btnEnrollBackButtonPushed(app, event)
            app.pnlEnroll.Visible = 'off';
    app.pnlHome.Visible = 'on';
        end

        % Selection changed function: tblEnrollQueue
        function tblEnrollQueueSelectionChanged(app, event)
            % 1. זיהוי השורה שנבחרה
    indices = event.Selection; 
    if isempty(indices), return; end
    rowIdx = indices(1);
    
    % 2. שליפת שם הקובץ (מעמודה 4 - הימנית ביותר בעברית)
    if size(app.tblEnrollQueue.Data, 2) >= 4
        fileName = app.tblEnrollQueue.Data{rowIdx, 4}; 
    else
        return; % הגנה ממצב שהטבלה ריקה או לא בנויה טוב
    end
    
    % 3. חיפוש התמונה בזיכרון והצגתה
    found = false;
    if ~isempty(app.TempEnrollData)
        for i = 1:length(app.TempEnrollData)
            [~, fName, ~] = fileparts(app.TempEnrollData(i).imagePath);
            
            % השוואה חכמה לשם הקובץ
            if strcmp(fName, fileparts(fileName)) || strcmp([fName '.tif'], fileName) || strcmp(fName, fileName)
                data = app.TempEnrollData(i);
                
                % הצגה גרפית
                imshow(data.processedImg, 'Parent', app.axEnrollPreview);
                hold(app.axEnrollPreview, 'on');
                plot(app.axEnrollPreview, data.minutiae(:,1), data.minutiae(:,2), 'r.');
                hold(app.axEnrollPreview, 'off');
                title(app.axEnrollPreview, data.name);
                
                found = true;
                break;
            end
        end
    end
    
    % 4. טיפול במקרה שלא נמצאה תמונה
    if ~found
        cla(app.axEnrollPreview);
        title(app.axEnrollPreview, 'תמונה לא זמינה');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.pnlIdentify = uipanel(app.MainTab);
            app.Grid = uigridlayout(app.pnlIdentify);
            app.axStep1 = uiaxes(app.Grid);
            app.axStep1.XTick = [];
            app.axStep1.YTick = [];
            app.axStep1.ZTick = [];
            app.axStep2 = uiaxes(app.Grid);
            app.axStep2.XTick = [];
            app.axStep2.YTick = [];
            app.axStep2.ZTick = [];
            app.axStep3 = uiaxes(app.Grid);
            app.axStep3.XTick = [];
            app.axStep3.YTick = [];
            app.axStep3.ZTick = [];
            app.axStep4 = uiaxes(app.Grid);
            app.axStep4.XTick = [];
            app.axStep4.YTick = [];
            app.axStep4.ZTick = [];
            app.axStep5 = uiaxes(app.Grid);
            app.axStep5.XTick = [];
            app.axStep5.YTick = [];
            app.axStep5.ZTick = [];
            app.axStep6 = uiaxes(app.Grid);
            app.axStep6.XTick = [];
            app.axStep6.YTick = [];
            app.axStep6.ZTick = [];
            app.btnBackToHome = uibutton(app.Grid, 'push');
            app.btnBackToHome.ButtonPushedFcn = createCallbackFcn(app, @btnBackToHomeButtonPushed, true);
            app.btnBackToHome.Text = 'Back';
            app.lampMatch = uilamp(app.Grid);
            app.lblStatus = uilabel(app.Grid);
            app.lblStatus.Text = 'Ready';
            app.pnlMass = uipanel(app.MainTab);
            app.lblMassStatus = uilabel(app.pnlMass);
            app.lblMassStatus.Text = 'ממתין לנתונים...';
            app.tblMassResults = uitable(app.pnlMass);
            app.tblMassResults.ColumnName = {'קובץ'; 'אמת (ID)'; 'זוהה כ'; 'ציון'; 'סטטוס'};
            app.tblMassResults.RowName = {};
            app.btnMassBack = uibutton(app.pnlMass, 'push');
            app.btnMassBack.ButtonPushedFcn = createCallbackFcn(app, @btnMassBackButtonPushed, true);
            app.btnMassBack.Text = 'חזור';
            app.gaugeProgress = uigauge(app.pnlMass, 'linear');
            app.pnlHome = uipanel(app.MainTab);
            app.GridLayout = uigridlayout(app.pnlHome);
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = 'מערכת לזיהוי טביעות אצבע';
            app.btnMenuIdentify = uibutton(app.GridLayout, 'push');
            app.btnMenuIdentify.ButtonPushedFcn = createCallbackFcn(app, @btnMenuIdentifyButtonPushed, true);
            app.btnMenuIdentify.Text = 'זיהוי בודד (Single ID)';
            app.btnMenuEnroll = uibutton(app.GridLayout, 'push');
            app.btnMenuEnroll.ButtonPushedFcn = createCallbackFcn(app, @btnMenuEnrollButtonPushed, true);
            app.btnMenuEnroll.Text = 'הוספת משתמש (Enrollment)';
            app.btnMenuMass = uibutton(app.GridLayout, 'push');
            app.btnMenuMass.ButtonPushedFcn = createCallbackFcn(app, @btnMenuMassButtonPushed, true);
            app.btnMenuMass.Text = 'זיהוי המוני (Mass ID)';
            app.pnlEnroll = uipanel(app.MainTab);
            app.pnlEnroll.Title = 'Panel';
            app.axEnrollPreview = uiaxes(app.pnlEnroll);
            app.axEnrollPreview.XTick = [];
            app.axEnrollPreview.YTick = [];
            app.btnEnrollLoad = uibutton(app.pnlEnroll, 'push');
            app.btnEnrollLoad.ButtonPushedFcn = createCallbackFcn(app, @btnEnrollLoadButtonPushed, true);
            app.btnEnrollLoad.Text = 'טען תמונה';
            app.btnEnrollSave = uibutton(app.pnlEnroll, 'push');
            app.btnEnrollSave.ButtonPushedFcn = createCallbackFcn(app, @btnEnrollSaveButtonPushed, true);
            app.btnEnrollSave.Text = 'שמור למאגר';
            app.btnEnrollBack = uibutton(app.pnlEnroll, 'push');
            app.btnEnrollBack.ButtonPushedFcn = createCallbackFcn(app, @btnEnrollBackButtonPushed, true);
            app.btnEnrollBack.Text = 'חזור';
            app.tblEnrollQueue = uitable(app.pnlEnroll);
            app.tblEnrollQueue.ColumnName = {'סטטוס'; 'נקודות'; 'שם מזוהה'; 'קובץ'};
            app.tblEnrollQueue.RowName = {};
            app.tblEnrollQueue.SelectionChangedFcn = createCallbackFcn(app, @tblEnrollQueueSelectionChanged, true);
            app.LabTab = uitab(app.TabGroup);
            app.LabTab.Title = 'Lab';
            app.axLabPreview = uiaxes(app.LabTab);
            title(app.axLabPreview, 'Title')
            xlabel(app.axLabPreview, 'X')
            ylabel(app.axLabPreview, 'Y')
            zlabel(app.axLabPreview, 'Z')
            app.SliderLabel = uilabel(app.LabTab);
            app.SliderLabel.Text = 'Slider';
            app.sliderGabor = uislider(app.LabTab);
            app.DatabaseTab = uitab(app.TabGroup);
            app.DatabaseTab.Title = 'Database';
            app.Label = uilabel(app.DatabaseTab);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end